classdef maingui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        ExitMenu                    matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        ViewMenu                    matlab.ui.container.Menu
        HelpMenu                    matlab.ui.container.Menu
        AboutMenu                   matlab.ui.container.Menu
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        SimulationlaunchpanelPanel  matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        Gauge2                      matlab.ui.control.SemicircularGauge
        Gauge2Label                 matlab.ui.control.Label
        SimTimesLabel               matlab.ui.control.Label
        StarttimeEditField          matlab.ui.control.NumericEditField
        StarttimeEditFieldLabel     matlab.ui.control.Label
        OpenButton                  matlab.ui.control.Button
        TimestepEditField           matlab.ui.control.NumericEditField
        TimestepEditFieldLabel      matlab.ui.control.Label
        FinaltimeEditField          matlab.ui.control.NumericEditField
        FinaltimeEditFieldLabel     matlab.ui.control.Label
        IdleLamp                    matlab.ui.control.Lamp
        IdleLampLabel               matlab.ui.control.Label
        RunButton                   matlab.ui.control.Button
        TabGroup                    matlab.ui.container.TabGroup
        SimulationTab               matlab.ui.container.Tab
        Robot1Panel                 matlab.ui.container.Panel
        TrjProfileDropDown_2        matlab.ui.control.DropDown
        TrjProfileDropDown_2Label   matlab.ui.control.Label
        EulerSetDropDown            matlab.ui.control.DropDown
        EulerSetDropDownLabel       matlab.ui.control.Label
        OrnLabel                    matlab.ui.control.Label
        TrnLabel                    matlab.ui.control.Label
        AccEditField                matlab.ui.control.NumericEditField
        AccEditFieldLabel           matlab.ui.control.Label
        VelEditField                matlab.ui.control.NumericEditField
        VelEditFieldLabel           matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        TrjTypeDropDown             matlab.ui.control.DropDown
        TrjTypeDropDownLabel        matlab.ui.control.Label
        TrajectorySpaceButtonGroup  matlab.ui.container.ButtonGroup
        TaskSpaceButton             matlab.ui.control.RadioButton
        JointSpaceButton            matlab.ui.control.RadioButton
        UITable2_2                  matlab.ui.control.Table
        UITable2                    matlab.ui.control.Table
        Robot1Tab                   matlab.ui.container.Tab
        Panel                       matlab.ui.container.Panel
        Spinner_6                   matlab.ui.control.Spinner
        Spinner_5                   matlab.ui.control.Spinner
        Spinner_4                   matlab.ui.control.Spinner
        Spinner_3                   matlab.ui.control.Spinner
        Spinner_2                   matlab.ui.control.Spinner
        Spinner                     matlab.ui.control.Spinner
        UITable                     matlab.ui.control.Table
        RobotselectionPanel         matlab.ui.container.Panel
        EEDropDown                  matlab.ui.control.DropDown
        EEDropDownLabel             matlab.ui.control.Label
        ModelDropDown               matlab.ui.control.DropDown
        ModelDropDownLabel          matlab.ui.control.Label
        Robot2Tab                   matlab.ui.container.Tab
        TextArea                    matlab.ui.control.TextArea
        Label_4                     matlab.ui.control.Label
        Tree                        matlab.ui.container.Tree
        GeneralNode                 matlab.ui.container.TreeNode
        DHparametersNode            matlab.ui.container.TreeNode
        LinkmassesNode              matlab.ui.container.TreeNode
        InertiatensorsNode          matlab.ui.container.TreeNode
        CenterofmassesNode          matlab.ui.container.TreeNode
        RightPanel                  matlab.ui.container.Panel
        DetachButton                matlab.ui.control.Button
        PlayButton                  matlab.ui.control.Button
        DropDown                    matlab.ui.control.DropDown
        DropDownLabel               matlab.ui.control.Label
        MaximizeButton              matlab.ui.control.Button
        HighqualitymodelsCheckBox   matlab.ui.control.CheckBox
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
       timerObj %timer
    end
    
    properties (Access = public)
        tini = '0'; % Start time
        tfin = '25'; % Final time
        tstp = '0.0001'; % Time step
        
        robot_model_1 = 2;
        ee_att_1 = 3;
        
        robot_model_2 = 3;
        ee_att_2 = 4;
        
        prcnt_1;        
        ip_1;
        fp_1;
        tf_1;
        rpt_num_1;
        q_posIni_1;
        
        prcnt_2;
        ip_2;
        fp_2;
        tf_2;
        rpt_num_2;
        
        f_des;
        
        trj_space_1   = 1; % 0:joint, 1:task (operational)
        ea_set_1      = 4; % 1:zxz, 2:zyz, 3:zyx, 4:xyz
        trj_type_1    = 2; % 1: sinusoidal, 2: p2p
        trj_profile_1 = 3; % 1:linear, 2:cubic, 3:quintic, 4: trapez, 5: S-trapez
        
        ctrl_space  = 1; % 0:joint, 1:task (operational)
        ctc_switch  = 0;
        force_ffwd  = 1;
        ctrl_at_end = 1;
        orn_at_norm = 1;
        trj_at_end  = 0;
        
        cnc_comp = 1; % Coriolis and centrifugal torque compensation
        grv_comp = 1; % Gravitational torque compensation
        spr_comp = 1; % Spring effect compensation
        frc_comp = 0; % Joint friction compensation
        prdc_frc = 0; % Predictive friction
        
        trj_space_2   = 1; % 0:joint, 1:task (operational)
        ea_set_2      = 4; % 1:zxz, 2:zyz, 3:zyx, 4:xyz
        trj_type_2    = 1; % 1: sinusoidal, 2: p2p
        trj_profile_2 = 3; % 1:linear, 2:cubic, 3:quintic, 4: trapez, 5: S-trapez

        Kp;
        Ki;
        Kd;
        Kpf;
        Kif;
        Kdf;
        Km;
        Kc;
        K;
        
        switch_val = 30;
        Sx_diag;
        Sf_diag;
        
        simOut;
        simTime;
        simmFbk;
    end
    
    methods (Access = private)      
        function getModelValues(app)
            app.simTime =  get_param('meam_simulink','SimulationTime');
            app.Label.Text = append(num2str(100*(app.simTime/str2double(app.tfin)),'%.3f'),'%');
            app.Gauge2.Value = 100*(app.simTime/str2double(app.tfin));
        end    
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            % Robot 1
            % prcnt = [0.3, 0.1];
            app.prcnt_1 = [0.07, 0.05];
            if app.trj_space_1 == 0 % joint space trajectory
                qi_1 = deg2rad([-30;60;12;-20;60;-35]);
                qf_1 = deg2rad([45;-35;115;40;35;15]);
                
                app.ip_1 = qi_1;
                app.fp_1 = qf_1;
            else % task space trajectory
                if app.ctrl_at_end == 1
                    p_ei_1 = [-0.40;1.05;1.0];
                else
                    p_ei_1 = [-0.20;1.05;1.0];
            %         p_ei_1 = [-0.40;1.05;1.0];
                end
                r_ei_1 = deg2rad([-90;00;00]);
                if app.trj_type_1 ~= 1
                    if app.ctrl_at_end == 1
                        p_ef_1 = [-0.40;1.45;1.0];
                    else
                        p_ef_1 = [-0.20;1.05;1.0];
            %             p_ef_1 = [-0.40;1.45;1.0];
                    end
                    r_ef_1 = deg2rad([-90;00;00]);
                else
                    Dp_1 = [0;-0.2;0];
                    Dr_1 = deg2rad([0;0;0]);
                    
                    p_ef_1 = p_ei_1+Dp_1;
                    r_ef_1 = r_ei_1+Dr_1;
                end
                
                app.ip_1 = [p_ei_1;r_ei_1];
                app.fp_1 = [p_ef_1;r_ef_1];
                
                qi_1 = getInitialPoints(p_ei_1,r_ei_1,app.robot_model_1,app.ee_att_1,app.ea_set_1,1);
            end
            
            app.q_posIni_1 = qi_1;
            
            app.prcnt_2 = [0.07, 0.05];
            if app.trj_space_2 == 0 % joint space trajectory
                qi_2 = deg2rad([-30;60;12;-20;60;-35]);
                qf_2 = deg2rad([45;-35;115;40;35;15]);
                
                app.ip_2 = qi_2;
                app.fp_2 = qf_2;
            else % task space trajectory
                p_ei_2 = [-0.05;-1.10;1.0];
                r_ei_2 = deg2rad([60;00;00]);
            %     r_ei_2 = deg2rad([90;00;00]);
                if app.trj_type_2 ~= 1
                    p_ef_2 = [0.85;-0.75;1.2];
                    r_ef_2 = deg2rad([110;-20;20]);
                else
                    Dp_2 = [0;0;0];
                    Dr_2 = deg2rad([20;00;00]);
                    
                    p_ef_2 = p_ei_2 + Dp_2;
                    r_ef_2 = r_ei_2 + Dr_2;
                end
                
                app.ip_2 = [p_ei_2;r_ei_2];
                app.fp_2 = [p_ef_2;r_ef_2];
                
                qi_2 = getInitialPoints(p_ei_2,r_ei_2,app.robot_model_2,app.ee_att_2,app.ea_set_2,1);
            end
            
            app.TaskSpaceButton.Value = app.trj_space_1;
            app.TrjProfileDropDown_2.Value = num2str(app.trj_profile_1);
            app.VelEditField.Value = app.prcnt_1(1)*100;
            app.AccEditField.Value = app.prcnt_1(2)*100;
            
            app.UITable2.Data = {p_ei_1(1) p_ei_1(2) p_ei_1(3);p_ef_1(1) p_ef_1(2) p_ef_1(3)};
            app.UITable2_2.Data = {r_ei_1(1) r_ei_1(2) r_ei_1(3);r_ef_1(1) r_ef_1(2) r_ef_1(3)};
            
            light(app.UIAxes,'Position',[-1 0 1])
            view(app.UIAxes,3)                             % Isometric view
            
            anm.robot_1_on = 1;
            anm.robot_2_on = 1;
            anm.base_on = 1;
            anm.high_quality = 1;
            
            rbt.robot_model_1 = app.robot_model_1;
            rbt.ee_att_1 = app.ee_att_1;
            rbt.robot_model_2 = app.robot_model_2;
            rbt.ee_att_2 = app.ee_att_2;
            
            out.jointPosFbk.Data = qi_1';
            out.jointPosDes.Data = qi_1';
            out.jointPosDes2.Data = qi_2';
            out.eePosCompress.Data = 0;
            
            plotAxes(app.UIAxes,anm,rbt,out)
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {481, 481};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {330, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Menu selected function: AboutMenu
        function AboutMenuSelected(app, event)
            message = sprintf('Robot Simulation Program\nVersion 0.000001');
            uialert(app.UIFigure,message,'About','Icon','info');
        end

        % Button pushed function: OpenButton
        function OpenButtonPushed(app, event)
            open_system('meam_simulink')
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            
            app.IdleLampLabel.Text = 'Initializing...';
            
            if app.trj_type_1 ~= 1
                app.rpt_num_1 = 1;
                app.tf_1 = computeTrajectoryTime(app.ip_1,app.fp_1,app.prcnt_1,app.trj_space_1,app.trj_profile_1,app.robot_model_1);
            else
                app.rpt_num_1 = 2;
                period_1 = 15;
            
                app.tf_1 = app.rpt_num_1 * period_1;
            end

            if app.trj_type_2 ~= 1
                app.rpt_num_2 = 1;
                app.tf_2 = computeTrajectoryTime(app.ip_2,app.fp_2,app.prcnt_2,app.trj_space_2,app.trj_profile_2,app.robot_model_2);
            else
                app.rpt_num_2 = 10;
                period_2 = 3;
            
                app.tf_2 = app.rpt_num_2 * period_2;
            end

            if app.ctrl_space == 0      % joint space control
                if app.ctc_switch == 0  % PID control
                    app.Kp = [3800;10000;7500;3000;1100;500];
                    app.Ki = [0;0;0;0;0;0];
                    app.Kd = [10;10;10;10;10;10];
                else                % Computed torque control
                    app.Kp = [2250;2500;900;300;250;250];
                    app.Ki = [30;110;90;30;15;15];
                    app.Kd = [85;100;30;10;10;10];
                end
            else                    % task space control
                if app.ctc_switch == 0  % PID control
                    app.Kp = [2500;2500;2500;550;550;550];
                    app.Kd = [5;15;15;2;2;2];
                    app.Ki = [10;10;10;3;3;3];
                else                % Computed torque control
                    app.Kp = [2550;2250;2750;1150;850;650];
                    app.Ki = [20;20;20;5;5;5];
                    app.Kd = [10;10;10;7;7;7];
                end
            end
            app.Km = [1;1;1;1;1;1];
            
            app.switch_val = 30;
            % switch_val = 00;
            if app.ctrl_at_end == 1
                if app.orn_at_norm == 1
                    app.Sx_diag = [1;1;0;1;1;1];
                    app.Sf_diag = [0;0;1;0;0;0];
                else
                    app.Sx_diag = [1;1;0;1;1;1];
                    app.Sf_diag = [0;0;1;0;0;0];
                end
            else
                app.Sx_diag = [1;0;1;1;1;1];
                app.Sf_diag = [0;1;0;0;0;0];
            end
            
            % Solid object stiffness
            % K = 15000; % Stifness
            app.K = 150000; % Stifness
            app.K = 15000; % Stifness
            % K = 0; % Stifness
            app.Kc = 0.001; % Compliance (inverse of stiffness)
            % Kc = 0.05; % Compliance (inverse of stiffness)
            % Kc = 0.1; % Compliance (inverse of stiffness)
            app.Kc = 0.005; % Compliance (inverse of stiffness)

            if app.ctrl_at_end == 1
                app.f_des = [0;0;60;0;0;0];
                
                app.Kpf = [0;10;3;0;0;0];
                app.Kif = [0;0;1;0;0;0];
                app.Kdf = [0;0;10;0;0;0];
            else
                app.f_des = [0;60;0;0;0;0];
                
                app.Kpf = [0;3;0;0;0;0];
                app.Kif = [0;0.05;0;0;0;0];
                app.Kdf = [0;10;0;0;0;0];
            end
     
            model = 'meam_simulink';
            load_system(model)
            
            assignin('base',"robot_model_1",app.robot_model_1);
            assignin('base',"ee_att_1",app.ee_att_1);
            assignin('base',"robot_model_2",app.robot_model_2);
            assignin('base',"ee_att_2",app.ee_att_2);
            
            assignin('base',"trj_space_1",app.trj_space_1);
            assignin('base',"ea_set_1",app.ea_set_1);
            assignin('base',"trj_type_1",app.trj_type_1);
            assignin('base',"trj_profile_1",app.trj_profile_1);
            
            assignin('base',"trj_space_2",app.trj_space_2);
            assignin('base',"ea_set_2",app.ea_set_2);
            assignin('base',"trj_type_2",app.trj_type_2);
            assignin('base',"trj_profile_2",app.trj_profile_2);
            
            assignin('base',"prcnt_1",app.prcnt_1);
            assignin('base',"ip_1",app.ip_1);
            assignin('base',"fp_1",app.fp_1);
            assignin('base',"tf_1",app.tf_1);
            assignin('base',"rpt_num_1",app.rpt_num_1);
            
            assignin('base',"prcnt_2",app.prcnt_2);
            assignin('base',"ip_2",app.ip_2);
            assignin('base',"fp_2",app.fp_2);
            assignin('base',"tf_2",app.tf_2);
            assignin('base',"rpt_num_2",app.rpt_num_2);
            
            assignin('base',"q_posIni_1",app.q_posIni_1);
            
            assignin('base',"ctrl_space",app.ctrl_space);
            assignin('base',"ctc_switch",app.ctc_switch);
            assignin('base',"force_ffwd",app.force_ffwd);
            assignin('base',"ctrl_at_end",app.ctrl_at_end);
            assignin('base',"orn_at_norm",app.orn_at_norm);
            assignin('base',"trj_at_end",app.trj_at_end);
        
            assignin('base',"cnc_comp",app.cnc_comp);
            assignin('base',"grv_comp",app.grv_comp);
            assignin('base',"spr_comp",app.spr_comp);
            assignin('base',"frc_comp",app.frc_comp);
            assignin('base',"prdc_frc",app.prdc_frc);
            
            assignin('base',"Kp",app.Kp);
            assignin('base',"Ki",app.Ki);
            assignin('base',"Kd",app.Kd);
            assignin('base',"Km",app.Km);
            assignin('base',"Kc",app.Kc);
            assignin('base',"K",app.K);

            assignin('base',"f_des",app.f_des);
            assignin('base',"Kpf",app.Kpf);
            assignin('base',"Kif",app.Kif);
            assignin('base',"Kdf",app.Kdf);
            assignin('base',"switch_val",app.switch_val);
            assignin('base',"Sx_diag",app.Sx_diag);
            assignin('base',"Sf_diag",app.Sf_diag);

            app.IdleLampLabel.Text = 'Running';
            app.IdleLamp.Color = [0,1,0];
            app.RunButton.Enable = 'off';
            app.PlayButton.Enable = 'off';
            app.DetachButton.Enable = 'off';
            app.IdleLampLabel.Text = '';
            app.Label.Visible = 'on';
            
            app.timerObj = timer('TimerFcn',@(~,~)getModelValues(app), 'Period',0.1, 'ExecutionMode', 'fixedSpacing', 'BusyMode','drop');    
            start(app.timerObj);
            
            app.simOut = sim(model,'StartTime',app.tini,'StopTime',app.tfin,'FixedStep',app.tstp);
            
            stop(app.timerObj);
            
            app.Label.Visible = 'off';
            app.RunButton.Enable = 'on';
            app.PlayButton.Enable = 'on';
            app.DetachButton.Enable = 'on';
            app.IdleLampLabel.Text = 'Finished';
            app.IdleLamp.Color = [0.5,0.5,0.5];
            app.Gauge2.Value = 100;

        end

        % Menu selected function: ExitMenu
        function ExitMenuSelected(app, event)
            delete(app);
        end

        % Value changed function: FinaltimeEditField
        function FinaltimeEditFieldValueChanged(app, event)
            value = app.FinaltimeEditField.Value;
            app.tfin = num2str(value);
        end

        % Value changed function: TimestepEditField
        function TimestepEditFieldValueChanged(app, event)
            value = app.TimestepEditField.Value;
            app.tstp = num2str(value);
        end

        % Value changed function: StarttimeEditField
        function StarttimeEditFieldValueChanged(app, event)
            value = app.StarttimeEditField.Value;
            app.tini = num2str(value);
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            
%             stop(app.timerObj);
            
            anm.robot_1_on = 1;
            anm.robot_2_on = 1;
            anm.base_on = 1;
            anm.high_quality = 1;
            
            rbt.robot_model_1 = app.robot_model_1;
            rbt.ee_att_1 = app.ee_att_1;
            rbt.robot_model_2 = app.robot_model_2;
            rbt.ee_att_2 = app.ee_att_2;
            
            cla(app.UIAxes)
%             light(app.UIAxes,'Position',[-1 0 1],'Style','local')
            light(app.UIAxes,'Position',[-1 0 1])
%             view(app.UIAxes,3)                             % Isometric view
            
            plotAxes(app.UIAxes,anm,rbt,app.simOut)
            
        end

        % Button pushed function: DetachButton
        function DetachButtonPushed(app, event)
            
%             stop(app.timerObj);
            
            anm.robot_1_on = 1;
            anm.robot_2_on = 1;
            anm.base_on = 1;
            anm.high_quality = 1;
            
            rbt.robot_model_1 = app.robot_model_1;
            rbt.ee_att_1 = app.ee_att_1;
            rbt.robot_model_2 = app.robot_model_2;
            rbt.ee_att_2 = app.ee_att_2;
            
            animFig = figure('Name','Animation Plot');
            animPlot = axes('Parent',animFig);
            
            cla(animPlot)
%             light(app.UIAxes,'Position',[-1 0 1],'Style','local')
            light(animPlot,'Position',[-1 0 1])
            view(animPlot,3)                             % Isometric view
            
            plotAxes(animPlot,anm,rbt,app.simOut)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.ExitMenu = uimenu(app.FileMenu);
            app.ExitMenu.MenuSelectedFcn = createCallbackFcn(app, @ExitMenuSelected, true);
            app.ExitMenu.Text = 'Exit';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.ViewMenu = uimenu(app.UIFigure);
            app.ViewMenu.Text = 'View';
            app.HelpMenu = uimenu(app.UIFigure);
            app.HelpMenu.Text = 'Help';
            app.AboutMenu = uimenu(app.HelpMenu);
            app.AboutMenu.MenuSelectedFcn = createCallbackFcn(app, @AboutMenuSelected, true);
            app.AboutMenu.Text = 'About';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.LeftPanel);
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.Robot1Panel = uipanel(app.SimulationTab);
            app.Robot1Panel.Title = 'Robot 1';
            app.UITable2 = uitable(app.Robot1Panel);
            app.UITable2.ColumnName = {'x'; 'y'; 'z'};
            app.UITable2.RowName = {'Initial'; 'Final'};
            app.UITable2_2 = uitable(app.Robot1Panel);
            app.UITable2_2.ColumnName = {'phi'; 'tht'; 'psi'};
            app.UITable2_2.RowName = {'Initial'; 'Final'};
            app.TrajectorySpaceButtonGroup = uibuttongroup(app.Robot1Panel);
            app.TrajectorySpaceButtonGroup.Title = 'Trajectory Space';
            app.JointSpaceButton = uiradiobutton(app.TrajectorySpaceButtonGroup);
            app.JointSpaceButton.Text = 'Joint Space';
            app.TaskSpaceButton = uiradiobutton(app.TrajectorySpaceButtonGroup);
            app.TaskSpaceButton.Text = 'Task Space';
            app.TaskSpaceButton.Value = true;
            app.TrjTypeDropDownLabel = uilabel(app.Robot1Panel);
            app.TrjTypeDropDownLabel.Text = 'Trj Type';
            app.TrjTypeDropDown = uidropdown(app.Robot1Panel);
            app.TrjTypeDropDown.Items = {'P2P', 'Sinusoidal'};
            app.TrjTypeDropDown.Value = 'P2P';
            app.Label_2 = uilabel(app.Robot1Panel);
            app.Label_2.Text = '%';
            app.Label_3 = uilabel(app.Robot1Panel);
            app.Label_3.Text = '%';
            app.VelEditFieldLabel = uilabel(app.Robot1Panel);
            app.VelEditFieldLabel.Text = 'Vel';
            app.VelEditField = uieditfield(app.Robot1Panel, 'numeric');
            app.AccEditFieldLabel = uilabel(app.Robot1Panel);
            app.AccEditFieldLabel.Text = 'Acc';
            app.AccEditField = uieditfield(app.Robot1Panel, 'numeric');
            app.TrnLabel = uilabel(app.Robot1Panel);
            app.TrnLabel.Text = 'Trn';
            app.OrnLabel = uilabel(app.Robot1Panel);
            app.OrnLabel.Text = 'Orn';
            app.EulerSetDropDownLabel = uilabel(app.Robot1Panel);
            app.EulerSetDropDownLabel.Text = 'Euler Set';
            app.EulerSetDropDown = uidropdown(app.Robot1Panel);
            app.EulerSetDropDown.Items = {'ZXZ', 'ZYZ', 'ZYX', 'XYZ'};
            app.EulerSetDropDown.Value = 'ZXZ';
            app.TrjProfileDropDown_2Label = uilabel(app.Robot1Panel);
            app.TrjProfileDropDown_2Label.Text = 'Trj Profile';
            app.TrjProfileDropDown_2 = uidropdown(app.Robot1Panel);
            app.TrjProfileDropDown_2.Items = {'Linear', 'Cubic', 'Quintic'};
            app.TrjProfileDropDown_2.ItemsData = {'1', '2', '3'};
            app.TrjProfileDropDown_2.Value = '3';
            app.Robot1Tab = uitab(app.TabGroup);
            app.Robot1Tab.Title = 'Robot 1';
            app.RobotselectionPanel = uipanel(app.Robot1Tab);
            app.RobotselectionPanel.Title = 'Robot selection';
            app.ModelDropDownLabel = uilabel(app.RobotselectionPanel);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.RobotselectionPanel);
            app.ModelDropDown.Items = {'RX160', 'RX160L', 'TX90'};
            app.ModelDropDown.Value = 'RX160';
            app.EEDropDownLabel = uilabel(app.RobotselectionPanel);
            app.EEDropDownLabel.Text = 'EE';
            app.EEDropDown = uidropdown(app.RobotselectionPanel);
            app.EEDropDown.Items = {'Sensor', 'None', 'Sensor+Gripper', 'Sensor+Ball&Spring', 'Sensor+Object', 'Sensor+Gripper+Object'};
            app.EEDropDown.Value = 'Sensor';
            app.Panel = uipanel(app.Robot1Tab);
            app.Panel.Title = 'Panel';
            app.UITable = uitable(app.Panel);
            app.UITable.ColumnName = {'a_i'; 'alpha_i'; 'd_i'; 'theta_i'};
            app.UITable.RowName = {'1'};
            app.UITable.ColumnEditable = [true false false false];
            app.Spinner = uispinner(app.Panel);
            app.Spinner_2 = uispinner(app.Panel);
            app.Spinner_3 = uispinner(app.Panel);
            app.Spinner_4 = uispinner(app.Panel);
            app.Spinner_5 = uispinner(app.Panel);
            app.Spinner_6 = uispinner(app.Panel);
            app.Robot2Tab = uitab(app.TabGroup);
            app.Robot2Tab.Title = 'Robot 2';
            app.Tree = uitree(app.Robot2Tab);
            app.GeneralNode = uitreenode(app.Tree);
            app.GeneralNode.Text = 'General';
            app.DHparametersNode = uitreenode(app.GeneralNode);
            app.DHparametersNode.Text = 'DH parameters';
            app.LinkmassesNode = uitreenode(app.GeneralNode);
            app.LinkmassesNode.Text = 'Link masses';
            app.InertiatensorsNode = uitreenode(app.GeneralNode);
            app.InertiatensorsNode.Text = 'Inertia tensors';
            app.CenterofmassesNode = uitreenode(app.GeneralNode);
            app.CenterofmassesNode.Text = 'Center of masses';
            app.Label_4 = uilabel(app.Robot2Tab);
            app.Label_4.Text = '';
            app.TextArea = uitextarea(app.Robot2Tab);
            app.TextArea.Value = {'Robot model: Stäubli RX160L'; ''; 'FT Sensor: On'; ''; 'End-effector: Rectangular rigid object'};
            app.SimulationlaunchpanelPanel = uipanel(app.LeftPanel);
            app.SimulationlaunchpanelPanel.Title = 'Simulation launch panel';
            app.RunButton = uibutton(app.SimulationlaunchpanelPanel, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.IdleLampLabel = uilabel(app.SimulationlaunchpanelPanel);
            app.IdleLampLabel.Text = 'Idle';
            app.IdleLamp = uilamp(app.SimulationlaunchpanelPanel);
            app.FinaltimeEditFieldLabel = uilabel(app.SimulationlaunchpanelPanel);
            app.FinaltimeEditFieldLabel.Text = 'Final time';
            app.FinaltimeEditField = uieditfield(app.SimulationlaunchpanelPanel, 'numeric');
            app.FinaltimeEditField.ValueChangedFcn = createCallbackFcn(app, @FinaltimeEditFieldValueChanged, true);
            app.FinaltimeEditField.Value = 25;
            app.TimestepEditFieldLabel = uilabel(app.SimulationlaunchpanelPanel);
            app.TimestepEditFieldLabel.Text = 'Time step';
            app.TimestepEditField = uieditfield(app.SimulationlaunchpanelPanel, 'numeric');
            app.TimestepEditField.ValueChangedFcn = createCallbackFcn(app, @TimestepEditFieldValueChanged, true);
            app.TimestepEditField.Value = 0.0001;
            app.OpenButton = uibutton(app.SimulationlaunchpanelPanel, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Text = 'Open';
            app.StarttimeEditFieldLabel = uilabel(app.SimulationlaunchpanelPanel);
            app.StarttimeEditFieldLabel.Text = 'Start time';
            app.StarttimeEditField = uieditfield(app.SimulationlaunchpanelPanel, 'numeric');
            app.StarttimeEditField.ValueChangedFcn = createCallbackFcn(app, @StarttimeEditFieldValueChanged, true);
            app.SimTimesLabel = uilabel(app.SimulationlaunchpanelPanel);
            app.SimTimesLabel.Text = 'Sim Time [s]:';
            app.Gauge2Label = uilabel(app.SimulationlaunchpanelPanel);
            app.Gauge2Label.Visible = 'off';
            app.Gauge2Label.Text = 'Gauge2';
            app.Gauge2 = uigauge(app.SimulationlaunchpanelPanel, 'semicircular');
            app.Label = uilabel(app.SimulationlaunchpanelPanel);
            app.Label.Visible = 'off';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.HighqualitymodelsCheckBox = uicheckbox(app.RightPanel);
            app.HighqualitymodelsCheckBox.Enable = 'off';
            app.HighqualitymodelsCheckBox.Text = 'High quality models';
            app.HighqualitymodelsCheckBox.Value = true;
            app.MaximizeButton = uibutton(app.RightPanel, 'push');
            app.MaximizeButton.Enable = 'off';
            app.MaximizeButton.Text = 'Maximize';
            app.DropDownLabel = uilabel(app.RightPanel);
            app.DropDownLabel.Enable = 'off';
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.RightPanel);
            app.DropDown.Enable = 'off';
            app.PlayButton = uibutton(app.RightPanel, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Enable = 'off';
            app.PlayButton.Text = 'Play';
            app.DetachButton = uibutton(app.RightPanel, 'push');
            app.DetachButton.ButtonPushedFcn = createCallbackFcn(app, @DetachButtonPushed, true);
            app.DetachButton.Enable = 'off';
            app.DetachButton.Text = 'Detach';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = maingui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end